classdef draftinterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LIMSVersion10UIFigure           matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        AddsamplesTab                   matlab.ui.container.Tab
        SampleCreationDateDatePicker_3Label  matlab.ui.control.Label
        SampleCreationDateDatePicker_3  matlab.ui.control.DatePicker
        OptionalCollaboratorInformationPanel_2  matlab.ui.container.Panel
        ReceivedDateDatePicker_3Label   matlab.ui.control.Label
        ReceivedDateDatePicker_3        matlab.ui.control.DatePicker
        ReceivingProtocolLinkEditField_3Label  matlab.ui.control.Label
        ReceivingProtocolLinkEditField_3  matlab.ui.control.EditField
        ManufacturerInfoLinkEditField_3Label  matlab.ui.control.Label
        ManufacturerInfoLinkEditField_3  matlab.ui.control.EditField
        WhoreceiveditDropDown_4Label    matlab.ui.control.Label
        WhoreceiveditDropDown_4         matlab.ui.control.DropDown
        WherediditcomefromDropDown_2Label  matlab.ui.control.Label
        WherediditcomefromDropDown_2    matlab.ui.control.DropDown
        NumberofsamplestocreateEditFieldLabel  matlab.ui.control.Label
        NumberofsamplestocreateEditField  matlab.ui.control.NumericEditField
        InitialNotesTextAreaLabel       matlab.ui.control.Label
        InitialNotesTextArea            matlab.ui.control.TextArea
        AddSamplesButton                matlab.ui.control.Button
        TypeLabel                       matlab.ui.control.Label
        TypeDropDown_2                  matlab.ui.control.DropDown
        DyeDropDown_2Label              matlab.ui.control.Label
        DyeDropDown_2                   matlab.ui.control.DropDown
        MoleculeDropDown_2Label         matlab.ui.control.Label
        MoleculeDropDown_2              matlab.ui.control.DropDown
        ParentIDEditField_3Label        matlab.ui.control.Label
        ParentIDEditField_3             matlab.ui.control.EditField
        MolarMassEditFieldLabel         matlab.ui.control.Label
        MolarMassEditField              matlab.ui.control.EditField
        StorageBoxDropDown_2Label       matlab.ui.control.Label
        StorageBoxDropDown_2            matlab.ui.control.DropDown
        StorageBufferDropDown_2Label    matlab.ui.control.Label
        StorageBufferDropDown_2         matlab.ui.control.DropDown
        StorageLocationDropDown_2Label  matlab.ui.control.Label
        StorageLocationDropDown_2       matlab.ui.control.DropDown
        ProtocolLinksPanel              matlab.ui.container.Panel
        SurfaceTreatmentEditFieldLabel  matlab.ui.control.Label
        SurfaceTreatmentEditField       matlab.ui.control.EditField
        HandlingProtocolsEditFieldLabel  matlab.ui.control.Label
        HandlingProtocolsEditField      matlab.ui.control.EditField
        ImagingBufferEditFieldLabel     matlab.ui.control.Label
        ImagingBufferEditField          matlab.ui.control.EditField
        ConcentrationAmountEditField_2Label  matlab.ui.control.Label
        ConcentrationAmountEditField_2  matlab.ui.control.NumericEditField
        QuantityEditField_2Label        matlab.ui.control.Label
        QuantityEditField_2             matlab.ui.control.NumericEditField
        WhosesampleisitDropDownLabel    matlab.ui.control.Label
        WhosesampleisitDropDown         matlab.ui.control.DropDown
        youmusthaveasampleownerandasampletypetoenabletheaddbuttonLabel  matlab.ui.control.Label
        UnitsEditField_3Label           matlab.ui.control.Label
        UnitsEditField_3                matlab.ui.control.EditField
        UnitsEditField_4Label           matlab.ui.control.Label
        UnitsEditField_4                matlab.ui.control.EditField
        UpdatesampleTab                 matlab.ui.container.Tab
        SampleIDEditFieldLabel          matlab.ui.control.Label
        SampleIDEditField               matlab.ui.control.EditField
        DisplayInfoButton               matlab.ui.control.Button
        UpdateSampleButton              matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        TypeDropDown_4Label             matlab.ui.control.Label
        TypeDropDown_4                  matlab.ui.control.DropDown
        DyeDropDown_3Label              matlab.ui.control.Label
        DyeDropDown_3                   matlab.ui.control.DropDown
        MoleculeDropDown_3Label         matlab.ui.control.Label
        MoleculeDropDown_3              matlab.ui.control.DropDown
        ParentIDEditField_5Label        matlab.ui.control.Label
        ParentIDEditField_5             matlab.ui.control.EditField
        MolarMassEditField_2Label       matlab.ui.control.Label
        MolarMassEditField_2            matlab.ui.control.EditField
        StorageBoxDropDown_3Label       matlab.ui.control.Label
        StorageBoxDropDown_3            matlab.ui.control.DropDown
        StorageBufferDropDown_3Label    matlab.ui.control.Label
        StorageBufferDropDown_3         matlab.ui.control.DropDown
        StorageLocationDropDown_4Label  matlab.ui.control.Label
        StorageLocationDropDown_4       matlab.ui.control.DropDown
        QuantityEditField_3Label        matlab.ui.control.Label
        QuantityEditField_3             matlab.ui.control.NumericEditField
        WhosesampleisitDropDown_2Label  matlab.ui.control.Label
        WhosesampleisitDropDown_2       matlab.ui.control.DropDown
        OptionalCollaboratorInformationPanel_3  matlab.ui.container.Panel
        ReceivedDateDatePicker_4Label   matlab.ui.control.Label
        ReceivedDateDatePicker_4        matlab.ui.control.DatePicker
        ReceivingProtocolLinkEditField_4Label  matlab.ui.control.Label
        ReceivingProtocolLinkEditField_4  matlab.ui.control.EditField
        ManufacturerInfoLinkEditField_4Label  matlab.ui.control.Label
        ManufacturerInfoLinkEditField_4  matlab.ui.control.EditField
        WhoreceiveditDropDown_5Label    matlab.ui.control.Label
        WhoreceiveditDropDown_5         matlab.ui.control.DropDown
        WherediditcomefromDropDown_3Label  matlab.ui.control.Label
        WherediditcomefromDropDown_3    matlab.ui.control.DropDown
        ProtocolLinksPanel_2            matlab.ui.container.Panel
        SurfaceTreatmentEditField_2Label  matlab.ui.control.Label
        SurfaceTreatmentEditField_2     matlab.ui.control.EditField
        HandlingProtocolsEditField_2Label  matlab.ui.control.Label
        HandlingProtocolsEditField_2    matlab.ui.control.EditField
        ConcentrationAmountEditField_3Label  matlab.ui.control.Label
        ConcentrationAmountEditField_3  matlab.ui.control.NumericEditField
        SampleCreationDateDatePicker_5Label  matlab.ui.control.Label
        SampleCreationDateDatePicker_5  matlab.ui.control.DatePicker
        StatusDropDownLabel             matlab.ui.control.Label
        StatusDropDown                  matlab.ui.control.DropDown
        FridgeRetrievalNumberEditFieldLabel  matlab.ui.control.Label
        FridgeRetrievalNumberEditField  matlab.ui.control.NumericEditField
        UnitsEditFieldLabel             matlab.ui.control.Label
        UnitsEditField                  matlab.ui.control.EditField
        UnitsEditField_2Label           matlab.ui.control.Label
        UnitsEditField_2                matlab.ui.control.EditField
        AddNotesTab                     matlab.ui.container.Tab
        NotesTextArea_2Label            matlab.ui.control.Label
        NotesTextArea_2                 matlab.ui.control.TextArea
        ImagingBufferEditField_3Label   matlab.ui.control.Label
        ImagingBufferEditField_3        matlab.ui.control.EditField
        WhatsamplewouldyouliketoaddnotesforEditFieldLabel  matlab.ui.control.Label
        WhatsamplewouldyouliketoaddnotesforEditField  matlab.ui.control.EditField
        CheckIDButton                   matlab.ui.control.Button
        AddnewnotesButton_2             matlab.ui.control.Button
        UITable3                        matlab.ui.control.Table
        ViewsamplesTab                  matlab.ui.container.Tab
        UITable2                        matlab.ui.control.Table
        SearchButton                    matlab.ui.control.Button
        UITable2_2                      matlab.ui.control.Table
        ChooseinformationtodisplayPanel  matlab.ui.container.Panel
        QuantityInformationVolumeConcetcCheckBox  matlab.ui.control.CheckBox
        StorageInformationBufferBoxLocationCheckBox  matlab.ui.control.CheckBox
        ReceivingInformationOriginDatereceivedetcCheckBox  matlab.ui.control.CheckBox
        TreatmentandHandlingProtocolLinksCheckBox  matlab.ui.control.CheckBox
        SampleOwnerDropDownLabel        matlab.ui.control.Label
        SampleOwnerDropDown             matlab.ui.control.DropDown
        CheckBox                        matlab.ui.control.CheckBox
        ParentIDDropDownLabel           matlab.ui.control.Label
        ParentIDDropDown                matlab.ui.control.DropDown
        SearchKeywordEditFieldLabel     matlab.ui.control.Label
        SearchKeywordEditField          matlab.ui.control.EditField
        SearchNotesButton               matlab.ui.control.Button
        DisplayallnotesButton           matlab.ui.control.Button
        StatusDropDown_2Label           matlab.ui.control.Label
        StatusDropDown_2                matlab.ui.control.DropDown
        CheckBox_2                      matlab.ui.control.CheckBox
        CheckBox_3                      matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        
        conn; % database connection
        error_log = 'errorlog.txt'
        %dbname;
        %username;
        %password;
        %driver;
        %dburl;
        %insertQueryResults;
        %fileChosen; % file chosen in the results
        %fileNameInDB;
        
        Emessage = 'Oh no! Something went wrong';
        
        Sample_to_see;
        relevant_notes;
        allorone;
        
        add_error_check = 0;
        update_error_check = 0;
        search_error_check = 0;
        
        display_checker = 0;
        update_Sample_statement = [];
        update_pid_statement = [];
        update_rid_statement = [];
        
        Sample_titles = [];
        rid_titles = [];
        pid_titles = [];
        
        Sample_id_update = [];
        rid = [];
        pid = [];
        
    end
    
    methods (Access = private)
        
        function new_Sample_id = generate_new_Sample_ID(app,type)
            
            % First of all we find the first letter of the Type
            char_type = char(type);
            type_letter = upper(char_type(2)); %actually the first letter of the Type 
            where_clause = strcat("WHERE Sample_ID LIKE '", type_letter, "%' ");
            
            % Then search for all ID numbers with that as the first letter,
            % and find the one with the highest number at the end
     
            query = strcat("SELECT Sample_ID FROM Sample ", where_clause, "ORDER BY Sample_ID DESC LIMIT 1");
            ID_table = select(app.conn, query);
            ID_cell = table2cell(ID_table);
            if isempty(ID_cell)
                % In this case, there are no ID's of this type in the
                % database already, the number should be one
                number = 1;
            else 
                % We have ID's of this type. We need to parse the number
                % from the ID, eg P000010 will parse a 10
                parsed_number = parse_the_ID(app,ID_cell);
                %Add one to the number since this is the next ID number
                number = parsed_number  + 1;
            end
            
            %now we need to do a sprintf to get the new ID with the new
            %number in the correct format.
            new_Sample_id = sprintf('%s%06d', type_letter, number);
        end
        
        function parsed_number = parse_the_ID(~,x)
            %we only input a cell into the array 
            
            %what the function does: converts the id number to a bunch of
            %characters, then creates an empty string. If the character
            %being checked is a 1 or higher digit, we then start appending
            %any numerical characters to the final_digit_string, which we
            %then finally convert back to a number
            
            strid = char(x);
            chars = num2cell(strid);
            final_digit_string = '';
            [~, len] = size(chars);
            check = 0;
            for k=1:len
                curnum = char(chars{k});
                tocheck = str2double(curnum);
                if isnumeric(tocheck) && ~isnan(tocheck)
                    if tocheck == 0 && check == 0
                        continue
                    elseif tocheck ~= 0 && check == 0
                        final_digit_string = strcat(final_digit_string, num2str(tocheck));
                        check = 1;
                    else 
                        final_digit_string = strcat(final_digit_string, num2str(tocheck));    
                    end
                end
            end
            parsed_number = str2double(final_digit_string);
        end
        
        function prepared = prepare(app, x)
            if isnumeric(x)
                x = num2str(x);
            end
            if isempty(x) || strcmp(x,"Please select...") || strcmp(x,"0") || strcmp(x, "NULL")
                x = "NULL";
            else 
                x = char(x);
                x = strrep(x,"'","''"); 
                x = strcat('''',x,'''');
            end
            prepared = x;
        end
        
        function entry = pns(app,x) 
            % This function just makes us able to search a table with
            % things being null changing to IS NULL instead of =
            column_name = inputname(2);
            if x == "NULL"
                entry = strcat(column_name, " IS NULL");
            else
                entry = strcat(column_name, " = ", x);
            end
        end


        function clause = getSelectClause(app, component, fieldName, tableShort)
            % check if the component is enabled
            if strcmp(component.Enable, 'on') && component.Value ~= "Please select..."
                
                %My code doesn't really need this if statement section, this was from
                %Wendy's, but in essence it searches the note by keyword.
                %Mine does this in a separate part!
                
                if strcmp(fieldName, 'note')
                    keyWord = component.Value;
                    keyWord = lower(keyWord);
                    clause = strcat(tableShort, ".", fieldName, " LIKE '%", keyWord, "%'"); 
                    
                % Special elseif where we start with the name, and we need
                % the ID number for the search. This does exactly that.
                elseif strcmp(fieldName, "Sample_Owner_ID")
                    inter_search = sprintf("SELECT Worker_ID FROM Workers WHERE Name = '%s'", component.Value);
                    name_searcher = select(app.conn, inter_search);
                    name = char(table2cell(name_searcher));
                    % Now we want to make the clause with the number!
                    clause = strcat(tableShort, ".", fieldName, "=", name);
                    
                %This is just boring when we only need the value
                elseif ischar(component.Value)
                    clause = strcat(tableShort, ".", fieldName, "='", component.Value, '''');
                %This is when it is a number
                else
                    clause = strcat(tableShort, ".", fieldName, "=", num2str(component.Value));
                end
            else
                clause = '';
            end           
        end
        
        function select_statement = getColumns(app, tableName, columns)
            % This function finds the column names as searchable things for
            % SQL to understand, have the tableName included in the column
            columns_list = "";
            for i = 1:length(columns)
                column_to_add = sprintf("%s.%s", tableName, columns(i));
                columns_list = strcat(columns_list, column_to_add, " ");
                if i == length(columns)
                    continue;
                end
                columns_list = strcat(columns_list, ", ");
            end
            
            select_statement = columns_list;
        end
        
        function data = collectMenuItems(app, name, table_name)
            
            % This function finds all the existing values for a certain
            % field in the database and is used to initialise drop down
            % menus when the app is opened
            
            selectQuery = sprintf('SELECT DISTINCT `%s` FROM %s', name, table_name);
            %disp(selectQuery);
            data = table2array(select(app.conn, selectQuery));
            data = vertcat("Please select...", data);
        end
        
        function clause = getFromTo(app, component1, component2, fieldName, tableShort)
            % This isn't used in my code, from Wendy's, but if the
            % functionality is added to search between two dates, this
            % would be the code to use :)
            if strcmp(component1.Enable, 'on') && strcmp(component2.Enable, 'on')
                from = component1.Value;
                to = component2.Value;
                if isdatetime(from)
                    from = datestr(from, 'yyyy-mm-dd');
                    to = datestr(to, 'yyyy-mm-dd');
                end
                completeFieldName = strcat(tableShort, ".", fieldName);
                if ischar(from)
                    clause = strcat(completeFieldName, ">='", from, "' AND", {' '}, ...
                       completeFieldName, "<='", to, "'");
                else
                    clause = strcat(completeFieldName, ">=", num2str(from), " AND", {' '}, ...
                       completeFieldName, "<=", num2str(to));
                end
            else
                clause = '';
            end
            
        end
        
        function toDisplay = numberconverter(app, data)
            % Prevents the NaN from displaying if the value is null
            data = cell2mat(table2cell(data));
            component = 0;
            if ~isnan(data)
                component = data;
            end
            toDisplay = component;
        end
        
        function write_to_log(app, log, preamble, statement)
            fid = fopen(log, "a+");
            fprintf(fid, "\n");
            fprintf(fid, strcat(preamble, statement, "\n"));
            fclose(fid);
        end
        
        function endResult = appenditem(app, component_value, title, relevant_statement, table_name)
            % This function appends items to the UPDATE statement every
            % time that the user changes something in the update menu
            if isempty(relevant_statement)
                relevant_statement = sprintf("UPDATE %s SET", table_name);
            end
            
            if isempty(component_value)
                new_part = sprintf(" `%s` = NULL,", char(title));    
            else
                if strcmp(title, "Fridge_Retrieval")
                    value_to_add = component_value;
                else
                    value_to_add = prepare(app, component_value);
                end
                new_part = sprintf(" `%s` = %s,", char(title), value_to_add);
            end
            
            relevant_statement = strcat(relevant_statement, new_part);
            endResult = relevant_statement;
        
        
        end
    end
    
    methods (Access = public)
        
        function switchFields(app, checkbox)
            % Basically this function just turns on some components when
            % the checkbox to the left of it is checked. It was more
            % complicated in Wendy's code, which is why it is more
            % copmlicated here, I just used hers, but don't worry about it
            % too much :) 
            position = checkbox.Position;
            left = position(1);
            bottom = position(2);
            children = app.ViewsamplesTab.Children;
            related_Cmpts = {};
            threshold = 999;
            for i = 1:size(children,1)
                targetPos = children(i).Position;
                targetLeft = targetPos(1);
                targetBottom = targetPos(2);
                if targetBottom == bottom && targetLeft > left
                    related_Cmpts{end+1} = children(i);
                    if strcmp(children(i).Type, 'uicheckbox')==1
                        threshold = children(i).Position(1);
                    end
                    if isprop(children(i), 'Children')
                        child = children(i);
                        grandchildren = child.Children; 
                        for j = 1:size(grandchildren, 1)
                            related_Cmpts{end+1} = grandchildren(j);
                        end
                    end
                end
            end
            
            for i = 1:length(related_Cmpts)
                target = related_Cmpts{i};
                if target.Position(1)>=threshold
                    related_Cmpts{i} = [];
                end       
            end
            
            if checkbox.Value==0
                for i = 1:length(related_Cmpts)
                    if isprop(related_Cmpts{i}, 'Enable')
                        related_Cmpts{i}.Enable = false;
                    end
                end
            else
                for i = 1:length(related_Cmpts)
                    if isprop(related_Cmpts{i}, 'Enable')
                        related_Cmpts{i}.Enable = true;
                    end
                end
            end
        end
        
        function log_the_error(app, ME, log)
            % If something goes wrong, this method is called. It will tell
            % us what went wrong, hopefully
            
            
            fid = fopen(log, "a+");
            fprintf(fid, "\n");
            time = datestr(clock,'YYYY/mm/dd HH:MM:SS:FFF');
            fprintf(fid,'Error at %23s\n',time);
            fprintf(fid, "Error Identifier: %s\n", ME.identifier);
            fprintf(fid, "Error Message: %s\n", ME.message);
            fclose(fid);
            
        end
        
        function results = successful_query(app, successful_queries, statement)
            fid = fopen(successful_queries, "a+");
            fprintf(fid, "\n");
            fprintf(fid, "")
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            load('program-info1.mat', 'username');
            load('program-info2.mat', 'password');
            %username = 'leslielab';
            %password = '!SlLaB19';
            app.conn = database('LIMSdb',username,password, ...
            'com.mysql.cj.jdbc.Driver',...
            'jdbc:mysql://arien-125.physics.mcgill.ca:3306/LIMSdb?useUnicode=true&useJDBCCompliantTimezoneShift=true&useLegacyDatetimeCode=false&serverTimezone=UTC&');
            
            % Get the basic titles
            temp = select(app.conn, "SELECT * FROM Sample");
            app.Sample_titles = temp.Properties.VariableNames;
            
            temp = select(app.conn, "SELECT * FROM Protocol_Information");
            app.pid_titles = temp.Properties.VariableNames;
            
            temp = select(app.conn, "SELECT * FROM Receiving_Info");
            app.rid_titles = temp.Properties.VariableNames;
            
            %We also need to have the add Samples disabled until we have a
            %type
            
            app.AddSamplesButton.Enable = 'off';
            
            % Create a record of who logged on when
            
            fid = fopen(app.error_log, 'a+');
            fprintf(fid, "Session opened at %s\n\n", datestr(clock,'YYYY/mm/dd HH:MM:SS:FFF'));
            fclose(fid);
            
            % We need to initialize the dropdowns in the Add Sample tab
            
            app.WhoreceiveditDropDown_4.Items = collectMenuItems(app, "Name", "Workers");
            app.WhosesampleisitDropDown.Items = collectMenuItems(app, "Name", "Workers");
            app.MoleculeDropDown_2.Items = collectMenuItems(app, "Molecule", "Sample");
            app.TypeDropDown_2.Items = collectMenuItems(app, "Type", "Sample");
            app.DyeDropDown_2.Items = collectMenuItems(app, "Dye", "Sample");
            app.StorageBoxDropDown_2.Items = collectMenuItems(app, "Storage_Box", "Sample");
            app.StorageBufferDropDown_2.Items = collectMenuItems(app, "Storage_Buffer", "Sample");
            app.StorageLocationDropDown_2.Items = collectMenuItems(app, "Storage_Location", "Sample");
            app.SampleCreationDateDatePicker_3.Value = datetime("now","TimeZone","local","Format","yyyy-MM-dd");
            app.WherediditcomefromDropDown_2.Items = collectMenuItems(app, "Origin", "Receiving_Info");
            
            % We need to initialize the dropdowns in the Update Sample tab
            
            app.WhosesampleisitDropDown_2.Items = app.WhosesampleisitDropDown.Items;
            app.WherediditcomefromDropDown_3.Items = app.WherediditcomefromDropDown_2.Items;
            app.StatusDropDown.Items = {'Active', 'Expired'};
            app.WhoreceiveditDropDown_5.Items = app.WhoreceiveditDropDown_4.Items;
            app.DyeDropDown_3.Items = app.DyeDropDown_2.Items;
            app.StorageBoxDropDown_3.Items = app.StorageBoxDropDown_2.Items;
            app.StorageBufferDropDown_3.Items = app.StorageBufferDropDown_2.Items;
            app.StorageLocationDropDown_4.Items = app.StorageLocationDropDown_2.Items;
            
            % Give ALL of them tags, so when we update things, we know
            % which column needs to be updated in the database table
            
            app.ParentIDEditField_5.Tag = char(app.Sample_titles(1,2));
            app.MoleculeDropDown_3.Tag = char(app.Sample_titles(1,3));
            app.TypeDropDown_4.Tag = char(app.Sample_titles(1,4));
            app.DyeDropDown_3.Tag = char(app.Sample_titles(1,5));
            app.MolarMassEditField_2.Tag = char(app.Sample_titles(1,6));
            app.SampleCreationDateDatePicker_5.Tag = char(app.Sample_titles(1,7));
            app.WhosesampleisitDropDown_2.Tag = char(app.Sample_titles(1,8));
            app.ConcentrationAmountEditField_3.Tag = char(app.Sample_titles(1,9));
            app.UnitsEditField.Tag = char(app.Sample_titles(1,10));
            app.QuantityEditField_3.Tag = char(app.Sample_titles(1,11));
            app.UnitsEditField_2.Tag = char(app.Sample_titles(1,12));
            app.StorageBufferDropDown_3.Tag = char(app.Sample_titles(1,13));
            app.StorageLocationDropDown_4.Tag = char(app.Sample_titles(1,14));
            app.StorageBoxDropDown_3.Tag = char(app.Sample_titles(1,15));
            app.StatusDropDown.Tag = char(app.Sample_titles(1,16));
            app.FridgeRetrievalNumberEditField.Tag = char(app.Sample_titles(1,19));
            
            app.SurfaceTreatmentEditField_2.Tag = char(app.pid_titles(1,2));
            app.HandlingProtocolsEditField_2.Tag = char(app.pid_titles(1,3));
            
            app.ReceivedDateDatePicker_4.Tag = char(app.rid_titles(1,2));
            app.WherediditcomefromDropDown_3.Tag = char(app.rid_titles(1,3));
            app.WhoreceiveditDropDown_5.Tag = char(app.rid_titles(1,4));
            app.ReceivingProtocolLinkEditField_4.Tag = char(app.rid_titles(1,5));
            app.ManufacturerInfoLinkEditField_4.Tag = char(app.rid_titles(1,6));
            
            % We need to initialize the dropdowns in the Search Tab
            app.SampleOwnerDropDown.Items = app.WhosesampleisitDropDown.Items;
            app.ParentIDDropDown.Items = collectMenuItems(app, "Sample_ID", "Sample");
            app.StatusDropDown_2.Items = {'Active', 'Expired'};
            
        end

        % Button pushed function: AddSamplesButton
        function AddSamplesButtonPushed(app, event)
             try 
                parent_ID = app.ParentIDEditField_3.Value;
                parent_ID = prepare(app, parent_ID);
                
                molecule = app.MoleculeDropDown_2.Value;
                molecule = prepare(app, molecule);
                
                
                type = app.TypeDropDown_2.Value;
                type = prepare(app, type);
                
                Sample_ID = generate_new_Sample_ID(app,type);
                Sample_ID = strcat('''', Sample_ID, '''');
                
                dye = app.DyeDropDown_2.Value;
                dye = prepare(app, dye);
                
                molar_mass = app.MolarMassEditField.Value;
                molar_mass = prepare(app, molar_mass);
                
                Sample_creator = app.WhosesampleisitDropDown.Value;
                if Sample_creator == "Please select..."
                    Sample_creator = "NULL";
                else
                    Sample_creator_stm = strcat("SELECT Worker_ID FROM Workers WHERE Name = '", Sample_creator, '''');
                    sd = select(app.conn, Sample_creator_stm);
                    Sample_creator = num2str(table2array(sd));
                end
                
                
                quantity = app.QuantityEditField_2.Value;
                quantity = prepare(app, quantity);
                
                quantity_units = app.UnitsEditField_4.Value;
                quantity_units = prepare(app, quantity_units);
                
                concentration = app.ConcentrationAmountEditField_2.Value;
                concentration = prepare(app, concentration);
                
                conc_units = app.UnitsEditField_3.Value;
                conc_units = prepare(app, conc_units);
                
                Sample_creation_date = app.SampleCreationDateDatePicker_3.Value;
                if isnat(Sample_creation_date)
                    Sample_creation_date = "NULL";
                else
                    Sample_creation_date = datestr(Sample_creation_date, 'yyyy-mm-dd');
                    Sample_creation_date = strcat('''', Sample_creation_date, '''');
                end
                
                
                today_datetime = datetime("now","TimeZone","local","Format","yyyy-MM-dd HH:mm:ss");
                today_datetime = datestr(today_datetime, "yyyy-mm-dd HH:MM:SS");
                
                storage_location = app.StorageLocationDropDown_2.Value;
                storage_location = prepare(app, storage_location);
                
                storage_box = app.StorageBoxDropDown_2.Value;
                storage_box = prepare(app, storage_box);
                
                storage_buffer = app.StorageBufferDropDown_2.Value;
                storage_buffer = prepare(app, storage_buffer);
                
                status_id = "'Active'";
                
                protocols_id = {};
                
                Receiving_Info_id = {}; 
                
                general_notes = string(app.InitialNotesTextArea.Value);
                general_note = "";
                for i = 1:length(general_notes)
                    general_note = strcat(general_note, "\n", general_notes(i));
                end
                
                general_notes = prepare(app, general_note);
                
                surface_treatment = app.SurfaceTreatmentEditField.Value;
                surface_treatment = prepare(app,surface_treatment);
                surface_treatment = strrep(surface_treatment,"\", "\\");
                
                handling_protocols = app.HandlingProtocolsEditField.Value;
                handling_protocols = prepare(app,handling_protocols);
                handling_protocols = strrep(handling_protocols,"\", "\\");
                
                imaging_buffer_description = app.ImagingBufferEditField.Value;
                imaging_buffer_description = prepare(app, imaging_buffer_description);
                
                physically_received_date = app.ReceivedDateDatePicker_3.Value;
                if isnat(physically_received_date)
                    physically_received_date = "NULL";
                else
                    physically_received_date = datestr(physically_received_date, 'yyyy-mm-dd');
                    physically_received_date = strcat('''', physically_received_date, '''');
                end
                
                
                origin = app.WherediditcomefromDropDown_2.Value;
                origin = prepare(app, origin);
                
                receiver = app.WhoreceiveditDropDown_4.Value;
                if receiver == "Please select..."
                    receiver_id = "NULL";
                else
                    receiver_stm = strcat("SELECT Worker_ID FROM Workers WHERE Name = '", receiver, '''');
                    rd = select(app.conn, receiver_stm);
                    receiver_id = num2str(table2array(rd));
                end
                
                receiving_protocol_link = app.ReceivingProtocolLinkEditField_3.Value;
                receiving_protocol_link = prepare(app, receiving_protocol_link);
                receiving_protocol_link = strrep(receiving_protocol_link,"\", "\\");
                
                manufacturer_info = app.ManufacturerInfoLinkEditField_3.Value;
                manufacturer_info = prepare(app,manufacturer_info);
                manufacturer_info = strrep(manufacturer_info,"\", "\\");
            %%{
            catch ME
                uialert(app.LIMSVersion10UIFigure, "Please make sure no fields have special characters", "error");
                log_the_error(app, ME, app.error_log);
                app.add_error_check = 1;
            end 
               % %}


            %%%%%%%%%%%%%%%%%%%%%%% PROTOCOLS INFO UPDATE %%%%%%%%%%%%%%%
            
            % Create a new entry into the protocols info table 
            number_to_add = app.NumberofsamplestocreateEditField.Value;
            for i = 1:number_to_add
                try
                    
                    surface_treatment_stm = pns(app, surface_treatment);
                    handling_protocol_stm = pns(app, handling_protocols);
                    
                    insert_protocols_info = strcat('INSERT INTO Protocol_Information VALUES(DEFAULT,',...
                        surface_treatment, ',' , handling_protocols, ')');
                    
                    execute(app.conn, insert_protocols_info);
                    
                    pid_search = "SELECT Protocols_ID FROM Protocol_Information ORDER BY Protocols_ID DESC LIMIT 1";
                    
                    pid_results = select(app.conn, pid_search);
                    
                    protocols_id{end+1} = num2str(table2array(pid_results));
                    
                    
                catch ME
                    write_to_log(app, app.error_log, "Problematic Protocols Query: ",pid_results);
                    uialert(app.LIMSVersion10UIFigure, "Something is problematic with your protocol links. Please revise. Sample was not added", "Error", "Icon", "error");
                    log_the_error(app, ME, app.error_log);
                    app.add_error_check = 1;
                end
            end
            
            
            %%%%%%%%%%%%%%%   RECEIVING INFO TABLE UPDATE %%%%%%%%%%%%%%%
            
            % Create a new entry into the receiving info table
            
            number_to_add = app.NumberofsamplestocreateEditField.Value;
            for i = 1:number_to_add
                try
                    insert_Receiving_Info = strcat('INSERT INTO Receiving_Info VALUES(DEFAULT,',...
                        physically_received_date, ',', origin, ',', receiver_id, ',', manufacturer_info, ...
                        ',',receiving_protocol_link, ')');
                    
                    execute(app.conn, insert_Receiving_Info);
                    %now find the ID of that entry and make that the receiving
                    %info ID
                    
                    %{
                    rid_search = strcat("SELECT Receiving_Info_ID FROM Receiving_Info WHERE ", prdstm, ...
                        " AND ", origin_stm , " AND ", receiver_id_stm, " AND ", ...
                        manufacturer_info_stm, " AND ", receiving_protocol_link_stm, " ORDER BY Receiving_Info_ID DESC LIMIT 1");
                    
                    %}
                    
                    rid_search = "SELECT Receiving_Info_ID FROM Receiving_Info ORDER BY Receiving_Info_ID DESC LIMIT 1";
                    rid_results = select(app.conn, rid_search);
                    
                    
                    Receiving_Info_id{end+1} = num2str(table2array(rid_results));
                    
                catch ME
                    write_to_log(app, app.error_log, "Problematic receiving info search query", rid_results);
                    uialert(app.LIMSVersion10UIFigure, "Something went wrong adding your receiving info. Sample was not addded. Try again!", "Error", "Icon", "error");
                    log_the_error(app, ME, app.error_log);
                    app.add_error_check = 1;
                end
            end
            
            %first Sample_id is used for the success message at the end
            first_Sample_id = Sample_ID;
            final_Sample_id = "will be changed";
            number_to_add = app.NumberofsamplestocreateEditField.Value;
            
            for i = 1:app.NumberofsamplestocreateEditField.Value
                
                protocol_id = protocols_id(i);
                Receiving_Info_idd = Receiving_Info_id(i);
                
                if app.add_error_check == 1
                    break;
                end
                
                if app.add_error_check ~= 1 
                    try
                       
                        Sample_add = strcat('INSERT INTO Sample VALUES (',...
                            Sample_ID, ',', parent_ID, ',', molecule, ',', type, ',',...
                            dye, ',', molar_mass, ',' ,Sample_creation_date, ',', Sample_creator, ',' ,...
                            concentration, ',' , conc_units, ',' , quantity, ',' , quantity_units, ',',...
                            storage_buffer, ',' , storage_location, ',', storage_box, ',' , status_id, ',', ...
                            protocol_id, ',' , Receiving_Info_idd, ',' , 'DEFAULT)') ;
                        
                        execute(app.conn, Sample_add);
                        write_to_log(app, app.error_log, "Sample added: ", Sample_add);
                        
                    catch ME
                        write_to_log(app, app.error_log, "Problematic Sample insert query: ", Sample_add);
                        uialert(app.LIMSVersion10UIFigure,"Something went wrong when adding the Sample.","Error", "Icon", "error");
                        log_the_error(app, ME, app.error_log);
                        app.add_error_check = 1;
                        break;
                    end
                else 
                    break; % because what's the point of adding any more to the database
                end
                
                % if the Sample has been successfully added, we want to try
                % to add the notes for the Sample!
                
                if app.add_error_check ~= 1 && ~(strcmp(imaging_buffer_description,"NULL") && strcmp(general_notes,"NULL"))
                    
            
                    imaging_add = strcat('INSERT IGNORE INTO General_Info VALUES (',...
                       Sample_ID, ',''' , today_datetime , ''',', imaging_buffer_description, ',' , general_notes, ')');
                        
                    imaging_add = string(imaging_add);
                        try
                            execute(app.conn, imaging_add);
                            write_to_log(app, app.error_log, "Notes added: ", imaging_add);
                        catch ME
                            write_to_log(app, app.error_log, "Problematic notes to add: ", imaging_add);
                            uialert(app.LIMSVersion10UIFigure, "Something went wrong adding the notes, but the Sample itself was still added. Try add the notes later!", "error");
                            % app.add_error_check = 1;
                        end
                else
                    %Just do nothing, it won't update this table and all will be dandy
                end
                
                %for the next iteration of the loop we need to have the
                %Sample_id updated.
                if i ~= number_to_add - 1 
                    final_Sample_id = Sample_ID;
                end
                Sample_ID = prepare(app, generate_new_Sample_ID(app, type));
            end
            
            %do the success message 
            if app.add_error_check == 0
                success_message_multiple = sprintf("Success! Samples %s-%s added to the database. Nice!", first_Sample_id, final_Sample_id);
                success_message_one = sprintf("Success! Sample %s added to the database. Hooray!", first_Sample_id);
                if number_to_add == 1
                    uialert(app.LIMSVersion10UIFigure,success_message_one,"Outcome", "Icon", "success")
                else 
                    uialert(app.LIMSVersion10UIFigure, success_message_multiple, "Outcome", "Icon", "success")
                end
                
            end
            %correct our thing for the end
            app.add_error_check = 0;
            % empty the protocols things
            protocols_id = [];
            Receiving_Info_id = [];
            

        end

        % Button pushed function: DisplayInfoButton
        function DisplayInfoButtonPushed(app, event)

            % This button will fill the fields with all the relevant
            % information
            app.UITable.Data = [];
            app.Sample_id_update = app.SampleIDEditField.Value;
            app.Sample_id_update = prepare(app, app.Sample_id_update);
            update_query = strcat("SELECT * FROM Sample WHERE Sample_id = ", app.Sample_id_update);
            relevant_entry = select(app.conn, update_query);
            
            
            if ~isempty(relevant_entry)
                app.UITable.Data = relevant_entry;
                app.UITable.ColumnName = app.Sample_titles;
                
                %Add the values to the edit boxes to make it easier for the
                %user to see what they want to change
                
                app.ParentIDEditField_5.Value = char(table2cell(relevant_entry(1,2)));
                app.MoleculeDropDown_3.Value = char(table2cell(relevant_entry(1,3)));
                app.TypeDropDown_4.Value = char(table2cell(relevant_entry(1,4))); 
                app.DyeDropDown_3.Value = char(table2cell(relevant_entry(1,5)));
                molar_string = numberconverter(app, relevant_entry(1,6));
                app.MolarMassEditField_2.Value = num2str(molar_string);
                
                app.SampleCreationDateDatePicker_5.Value = datetime(char(table2cell(relevant_entry(1,7))), "InputFormat", "uuuu-MM-dd");
                
                find_whose_it_is = table2array(relevant_entry(1,8));
                app.WhosesampleisitDropDown_2.Value = char(table2cell(select(app.conn, sprintf("SELECT Name FROM Workers WHERE Worker_ID = %d", find_whose_it_is))));
                
                app.ConcentrationAmountEditField_3.Value = numberconverter(app, relevant_entry(1,9));
                
               
                app.UnitsEditField.Value = char(table2cell(relevant_entry(1,10)));
                app.QuantityEditField_3.Value = numberconverter(app, relevant_entry(1,11));
                app.UnitsEditField_2.Value = char(table2cell(relevant_entry(1,12)));
                app.StorageBufferDropDown_3.Value = char(table2cell(relevant_entry(1,13)));
                app.StorageLocationDropDown_4.Value = char(table2cell(relevant_entry(1,14)));
                app.StorageBoxDropDown_3.Value = char(table2cell(relevant_entry(1,15)));
                app.StatusDropDown.Value = char(table2cell(relevant_entry(1,16)));
                fridge_num = numberconverter(app, relevant_entry(1,19));
                app.FridgeRetrievalNumberEditField.Value = double(fridge_num);
                
                % We need to find the protocols info now
                pid_number = table2array(relevant_entry(1,17));
                pid_number = prepare(app, pid_number);
                app.pid = pid_number;
                pid_update = strcat("SELECT * FROM Protocol_Information WHERE Protocols_ID = ", pid_number);
                pid_entry = select(app.conn, pid_update);
                
                app.SurfaceTreatmentEditField_2.Value = char(table2cell(pid_entry(1,2)));
                app.HandlingProtocolsEditField_2.Value = char(table2cell(pid_entry(1,3)));
                
                % Now we need the receiving info
                
                rid_number = table2array(relevant_entry(1,18));
                rid_number = prepare(app, rid_number);
                app.rid = rid_number;
                
                rid_update = strcat("SELECT * FROM Receiving_Info WHERE Receiving_Info_ID = ", rid_number);
                rid_entry = select(app.conn, rid_update);
                
                
                app.ReceivedDateDatePicker_4.Value = datetime(char(table2cell(rid_entry(1,2))), "InputFormat", "uuuu-MM-dd");
                app.WherediditcomefromDropDown_3.Value = char(table2cell(rid_entry(1,3)));
                
                receive = numberconverter(app, rid_entry(1,4));
                if receive == -2147483648
                
                else 
                    receiver_name = char(table2cell(select(app.conn, sprintf("SELECT Name FROM Workers WHERE Worker_ID = %d", receive))));
                    app.WhoreceiveditDropDown_5.Value = receiver_name;
                end
                app.ManufacturerInfoLinkEditField_4.Value = char(table2cell(rid_entry(1,5)));
                app.ReceivingProtocolLinkEditField_4.Value = char(table2cell(rid_entry(1,6)));
                
                
                app.display_checker = 1; 
               
            else
                app.UITable.Data = {'No results'};
            end
            
            
        end

        % Button pushed function: UpdateSampleButton
        function UpdateSampleButtonPushed(app, event)

            % What we want in the update is to execute the statement that
            % has been so beautifully constructed by our value changed
            % functions. Everytime a value was changed, it added a
            % statement to the _statements. Smooth!!
            
            dont_display_success = 0;
            
            if ~isempty(app.update_Sample_statement)
                temp = char(app.update_Sample_statement);
                temp = temp(1:end-1);
                temp = strcat(temp, " WHERE Sample_ID = ", app.Sample_id_update);
                app.update_Sample_statement = temp;
                try
                    execute(app.conn, app.update_Sample_statement);
                catch ME
                    write_to_log(app, app.error_log, "Problematic update query: ", app.update_Sample_statement);
                    uialert(app.LIMSVersion10UIFigure, "Something went wrong updating your Sample information", "Error", "Icon", "error");
                    log_the_error(app, ME, app.error_log);
                    dont_display_success = 1;
                end
                app.update_Sample_statement = [];
            end
            
            if ~isempty(app.update_pid_statement)
                temp = char(app.update_pid_statement);
                temp = temp(1:end-1);
                temp = strcat(temp, " WHERE Protocols_ID = ", app.pid);
                app.update_pid_statement = temp;
                try
                    execute(app.conn, app.update_pid_statement);
                catch ME
                    write_to_log(app, app.error_log, "Problematic update query: ", app.update_pid_statement);
                    uialert(app.LIMSVersion10UIFigure, "Something went wrong updating your protocol information", "Error", "Icon", "error");
                    log_the_error(app, ME, app.error_log);
                    dont_display_success = 1;
                end
                app.update_pid_statement = [];
            end
            
            if ~isempty(app.update_rid_statement)
                temp = char(app.update_rid_statement);
                temp = temp(1:end-1);
                temp = strcat(temp, " WHERE Receiving_Info_ID = ", app.rid);
                app.update_rid_statement = temp;
                
                
                try
                    execute(app.conn, app.update_rid_statement);
                catch ME
                    write_to_log(app, app.error_log, "Problematic update query: ", app.update_rid_statement);
                    uialert(app.LIMSVersion10UIFigure, "Something went wrong updating your receiving information", "Error", "Icon", "error");
                    log_the_error(app, ME, app.error_log);
                    dont_display_success = 1;
                end
                app.update_rid_statement = [];
            end
            
            % give us a note to say how successful we have been in updating
            % the Sample 
            
            if dont_display_success == 0
                success_message = sprintf("Successfuly updated Sample %s", app.Sample_id_update);
                uialert(app.LIMSVersion10UIFigure, success_message, "Outcome", "Icon", "success");
            end
            
       
        end

        % Value changed function: TypeDropDown_2, 
        % WhosesampleisitDropDown
        function TypeDropDown_2ValueChanged(app, event)
            % This function enables the add Samples button only when both a
            % type and an owner have been specified.
            value = app.TypeDropDown_2.Value;
            other_value = app.WhosesampleisitDropDown.Value;
            
            if (value ~= "Please select..." || ~isempty(value)) && other_value ~= "Please select..."
                app.AddSamplesButton.Enable = 'on';
            else
                app.AddSamplesButton.Enable = 'off';
            end
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)

            % We need to create the basic select statement. In the 'if
            % checked' things we will append the additional columns if we
            % need to :) 
            
            columns_to_add = ["Sample_ID", "Molecule", "Type", "Dye", "Sample_Creation_Date"];
            select_part = strcat("SELECT ", getColumns(app,"Sample", columns_to_add), ", wkr.Name AS 'Sample_Owner'");
            
            join_name = "LEFT JOIN Workers wkr ON wkr.Worker_ID = Sample.Sample_Owner_ID";
            join_receiving = "";
            join_handling = "";
            join_receiving_name = ""; 
            
            % What do I want to add at the end
            if app.QuantityInformationVolumeConcetcCheckBox.Value
                basic_info_columns = ["Molar_Mass", "Concentration", "Conc_Units" , "Quantity", "Quantity_Units" ];
                select_part = strcat(select_part, ", ", getColumns(app, "Sample", basic_info_columns));
            end
            
            if app.StorageInformationBufferBoxLocationCheckBox.Value
                storage_info_columns = ["Storage_Buffer", "Storage_Location", "Storage_Box"];
                select_part = strcat(select_part, ", ", getColumns(app, "Sample", storage_info_columns));
            end
            
            if app.ReceivingInformationOriginDatereceivedetcCheckBox.Value
                Receiving_Info_columns = ["Physically_Received_Date", "Origin", "Manufacturer_Info", "Receiving_Protocol_Link"]; %We had receiving info id here
                select_part = strcat(select_part, ", wkrs.Name AS 'Who received it', ", getColumns(app, "Receiving_Info", Receiving_Info_columns));
                %In here I also need the join part
                join_receiving = " LEFT JOIN Receiving_Info ON Sample.Receiving_Info_id = Receiving_Info.Receiving_Info_id ";
                join_receiving_name = "LEFT JOIN Workers wkrs ON wkrs.Worker_ID = Receiving_Info.Receiver_ID ";
            end
            
            if app.TreatmentandHandlingProtocolLinksCheckBox.Value
                handling_info_columns = ["Surface_Treatment", "Handling_Protocols"];
                select_part = strcat(select_part, ", ", getColumns(app, "Protocol_Information", handling_info_columns));
                % In here I also need the join part 
                join_handling = " LEFT JOIN Protocol_Information ON Sample.Protocols_ID = Protocol_Information.Protocols_ID ";
            end
            
            %Now we need to append our where clauses
            %Now lets check if we've built the search query correctly!
            
            final_search_query = strcat(select_part, "FROM Sample ", join_name, join_handling, join_receiving, join_receiving_name);
            %disp(final_search_query);
            
            % Assuming this works we now need to start making where clauses
            
            Sample_table_name = 'Sample';
            
            parent_where = getSelectClause(app, app.ParentIDDropDown, 'Parent_ID', Sample_table_name);
            owner_search = getSelectClause(app, app.SampleOwnerDropDown, 'Name', "wkr");
            status_where = getSelectClause(app, app.StatusDropDown_2, 'Status', Sample_table_name);

            listCri = [parent_where, owner_search, status_where];
            
            % check if there are any criterions
            if ~isempty(listCri)  
                listCri(cellfun('isempty',listCri)) = [];
                whereClause = join(listCri, " AND ");
            
                query = strcat(final_search_query, ' WHERE', {' '}, whereClause);
                data = select(app.conn, query);
                
            else
                % return all the data
                
                data = select(app.conn, final_search_query);
                
                
            end
            
            if ~isempty(data)
                
                app.UITable2.Data = data;
                titles = data.Properties.VariableNames;
                % Now titles will have two name columns, or just 
                check = 1;
                for i = 1:length(titles)
                    if strcmp(char(titles(i)), "Name") && check == 1
                        titles(i) = {'Sample Owner'};
                        check = 0;
                    elseif strcmp(char(titles(i)), "Name_1")
                        titles(i) = {'Who received it'};
                    end
                end
                app.UITable2.ColumnName = titles;
            else
                app.UITable2.Data = {'No results found'};
            end
            
            % perform a function on all of them, only if the corresponding
            % checkbox (which we can input) is set to on. 
            
            app.UITable2_2.Data = {'No results found'};
                        
            
        end

        % Cell selection callback: UITable2
        function UITable2CellSelection(app, event)
            indices = event.Indices;
            data = app.UITable2.Data;
            titles = data.Properties.VariableNames;
            
            % When a cell is selected, we either need to generate a link
            % from it, or do what we already did with displaying the
            % Samples :) 
            
            if strcmp('Handling_Protocols', titles(1,indices(2))) || strcmp('Surface_Treatment', titles(1,indices(2))) || strcmp('Manufacturer_Info', titles(1,indices(2))) || strcmp('Receiving_Protocol_Link', titles(1,indices(2)))
                % Now we know we need to convert whatever is in the table
                % into a link, potentially
                
                cell = app.UITable2.Data(indices(1), indices(2));
                cell = cell2mat(table2cell(cell));
                web(cell);
            else 
                table_id = app.UITable2.Data(indices(1),indices(2));
                app.Sample_to_see = cell2mat(table2cell(table_id));
                app.Sample_to_see = prepare(app, app.Sample_to_see);
                
                % now we want to have the second UI table display all the
                % points at which there is an entry in the general info table
                % with a Sample ID matching the one selected
                
                note_view_query = strcat("SELECT * FROM General_Info WHERE Sample_id = ", app.Sample_to_see);
                app.relevant_notes = select(app.conn, note_view_query);
                
                if ~isempty(app.relevant_notes)
                    app.UITable2_2.Data = app.relevant_notes;
                    titlesview = app.relevant_notes.Properties.VariableNames;
                    app.UITable2_2.ColumnName = titlesview;
                else
                    app.UITable2_2.Data = {'No results'};
                end
                
                app.allorone = 1;
                
            end
            
        end

        % Button pushed function: AddnewnotesButton_2
        function AddnewnotesButtonPushed(app, event)
            % We need to add the new notes to the thing
            Sample_to_peek = app.WhatsamplewouldyouliketoaddnotesforEditField.Value;
            Sample_to_peek = prepare(app, Sample_to_peek);
            
            today_datetime = datetime("now","TimeZone","local","Format","yyyy-MM-dd HH:mm:ss");
            today_datetime = datestr(today_datetime, "yyyy-mm-dd HH:MM:SS");
            
            new_imaging_buffer = app.ImagingBufferEditField_3.Value;
            new_imaging_buffer = prepare(app, new_imaging_buffer);
            
            % This bit is a little confusing with what I did with the notes
            % vs note, don't worry about it too much, it works 
            
            new_notes = string(app.NotesTextArea_2.Value)
            new_note = "";
            for i = 1:length(new_notes)
                new_note = strcat(new_note, "\n", new_notes(i));
            end
            new_notes = prepare(app, new_note);
            
            
            % Now we have everything, we just need to add it
            
            if new_notes ~= "''" 
                
                imaging_add = strcat('INSERT IGNORE INTO General_Info VALUES (',...
                Sample_to_peek, ',''' , today_datetime , ''',', new_imaging_buffer, ',' , new_notes, ')');
           
                imaging_add = string(imaging_add);
                check_here_now = 0;
                try 
                    execute(app.conn, imaging_add);
                catch ME
                    write_to_log(app, app.error_log, "Problematic notes to add(Sample already created): ", imaging_add);
                    uialert(app.LIMSVersion10UIFigure, "The ID number you are now using is not valid");
                    log_the_error(app, ME, app.error_log);
                    check_here_now = 1;
                end
                
                if check_here_now == 0
                    success_message = sprintf("Notes successfully added for Sample %s", Sample_to_peek);
                    uialert(app.LIMSVersion10UIFigure, success_message, "Outcome", "Icon", "success");
                end
                
            else
                uialert(app.LIMSVersion10UIFigure, "you haven't written anything!", 'Error');
            end
            
        end

        % Value changed function: CheckBox, CheckBox_2, CheckBox_3
        function CheckBoxValueChanged(app, event)
            value = event.Source;
            switchFields(app,value)
        end

        % Value changed function: ConcentrationAmountEditField_2
        function ConcentrationAmountEditField_2ValueChanged(app, event)
            value = app.ConcentrationAmountEditField_2.Value;
            if ~isempty(value)
                app.UnitsEditField_3.Enable = 'on';
            else
                app.UnitsEditField_3.Enable = 'off';
            end
        end

        % Value changed function: QuantityEditField_2
        function QuantityEditField_2ValueChanged(app, event)
            value = app.QuantityEditField_2.Value;
            if ~isempty(value)
                app.UnitsEditField_4.Enable = 'on';
            else
                app.UnitsEditField_4.Enable = 'off';
            end
        end

        % Value changed function: ConcentrationAmountEditField_3, 
        % DyeDropDown_3, FridgeRetrievalNumberEditField, 
        % MolarMassEditField_2, MoleculeDropDown_3, 
        % ParentIDEditField_5, QuantityEditField_3, 
        % SampleCreationDateDatePicker_5, StatusDropDown, 
        % StorageBoxDropDown_3, StorageBufferDropDown_3, 
        % StorageLocationDropDown_4, TypeDropDown_4, 
        % WhosesampleisitDropDown_2
        function Sample_Update_Appender(app, event)
            
            
            component_name = event.Source.Tag;
            if strcmp(component_name, "Fridge_Retrieval")
                component_value = num2str(event.Source.Value);
            else 
                component_value = char(event.Source.Value);
            end 
            
            
            if strcmp(component_value, "NaT") || strcmp(component_value, "NaN")
                component_value = 'NULL';
            end
            
            
            for i = 1:length(app.Sample_titles)
                string_to_check = char(app.Sample_titles(1,i));
                if strcmp(string_to_check,char(component_name))
                    app.update_Sample_statement = appenditem(app, component_value, app.Sample_titles(i), app.update_Sample_statement, "Sample");
                end
            end
            
            
        end

        % Value changed function: HandlingProtocolsEditField_2, 
        % SurfaceTreatmentEditField_2
        function PID_Update_Appender(app, event)
            component_name = event.Source.Tag;
            component_value = char(event.Source.Value);
            
            if strcmp(component_value, "NaT") || strcmp(component_value, "NaN")
                component_value = 'NULL';
            end
            
            for i = 1:length(app.pid_titles)
                string_to_check = char(app.pid_titles(1,i));
                if strcmp(string_to_check, char(component_name))
                    app.update_pid_statement = appenditem(app, component_value, app.pid_titles(i), app.update_pid_statement, "Protocol_Information");
                end
            end
            
        end

        % Value changed function: ManufacturerInfoLinkEditField_4, 
        % ReceivedDateDatePicker_4, 
        % ReceivingProtocolLinkEditField_4, 
        % WherediditcomefromDropDown_3, WhoreceiveditDropDown_5
        function RID_Update_Appender(app, event)
            component_name = event.Source.Tag;
            component_value = char(event.Source.Value);
            if strcmp(component_value, "NaT") || strcmp(component_value, "NaN")
                component_value = 'NULL';
            end
            
            if strcmp(component_name, "Receiver_ID")
                receiver_stm = strcat("SELECT Worker_ID FROM Workers WHERE Name = '", component_value, '''');
                rd = select(app.conn, receiver_stm);
                component_value = num2str(table2array(rd));
            end
            for i = 1:length(app.rid_titles)
                string_to_check = char(app.rid_titles(1,i));
                if strcmp(string_to_check, char(component_name))
                    app.update_rid_statement = appenditem(app, component_value, app.rid_titles(i), app.update_rid_statement, "Receiving_Info");
                end
            end
            
        end

        % Button pushed function: CheckIDButton
        function CheckIDButtonPushed(app, event)
            find = sprintf("SELECT Sample_ID FROM Sample WHERE Sample_ID = %s", prepare(app, app.WhatsamplewouldyouliketoaddnotesforEditField.Value));
            find_id = select(app.conn, find);
            
            if ~isempty(find_id)
                app.ImagingBufferEditField_3.Enable = 'on';
                app.NotesTextArea_2.Enable = 'on';
                note_history = sprintf("SELECT * FROM General_Info WHERE Sample_ID = %s", prepare(app, app.WhatsamplewouldyouliketoaddnotesforEditField.Value));
                note_history = select(app.conn, note_history);
                app.UITable3.Data = note_history;
                app.UITable3.ColumnName = note_history.Properties.VariableNames;
            else 
                uialert(app.LIMSVersion10UIFigure, "ID does not exist in the database. Check for a typo!", "Error", "Icon", "error");
                app.ImagingBufferEditField_3.Enable = 'off';
                app.NotesTextArea_2.Enable = 'off';
            end
            
        end

        % Button pushed function: DisplayallnotesButton
        function DisplayallnotesButtonPushed(app, event)
            % If this button is pushed, we just want to simply display the
            % table with the notes
            
            app.relevant_notes = select(app.conn, "SELECT * FROM General_Info");
            if ~isempty(app.relevant_notes)
                app.UITable2_2.Data = app.relevant_notes;
            else
                app.UITable2_2.Data = {'No notes exist in the database yet'};
            end
            
            app.allorone = 0;
            
        end

        % Button pushed function: SearchNotesButton
        function SearchNotesButtonPushed(app, event)
            % If this button is pushed, we want to have it so that the
            % keyword is used to search through the notes (also want to
            % have the imaging buffer!!!!
            
            keyword = app.SearchKeywordEditField.Value;
            if isempty(keyword)
                
            else
                if app.allorone == 0
                    % If this is true, all notes are displaying
                    all_search = sprintf("SELECT * FROM General_Info WHERE Imaging_Buffer_Text_Description LIKE '%%%s%%' OR General_Notes LIKE '%%%s%%'", keyword, keyword);
                    %disp(all_search);
                    search_notes = select(app.conn, all_search);
                    if isempty(search_notes)
                        app.UITable2_2.Data = {'No results found'};
                    else
                        app.UITable2_2.Data = search_notes;
                    end
                    
                else
                    %We know here that it must be searching within the
                    %notes for one Sample
                   
                    search_notes = sprintf("SELECT * FROM General_Info WHERE Sample_id = %s AND (Imaging_Buffer_Text_Description LIKE '%%%s%%' OR General_Notes LIKE '%%%s%%')", app.Sample_to_see, keyword, keyword);
                    search_notes = select(app.conn, search_notes);
                    
                    if isempty(search_notes)
                        app.UITable2_2.Data = {'No results found'};
                    else
                        app.UITable2_2.Data = search_notes;
                    end
                end
            end
        end

        % Value changing function: 
        % WhatsamplewouldyouliketoaddnotesforEditField
        function WhatsamplewouldyouliketoaddnotesforEditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.ImagingBufferEditField_3.Enable = 'off';
            app.NotesTextArea_2.Enable = 'off';
        end

        % Close request function: LIMSVersion10UIFigure
        function CloseFcn(app, event)
            
            fid = fopen(app.error_log, 'a+');
            fprintf(fid, "\n\n$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$\n\n")
            fclose(fid);
            
            delete(app);
        end

        % Cell selection callback: UITable2_2
        function UITable2_2CellSelection(app, event)
            indices = event.Indices;
            inter = app.UITable2_2.Data(indices(1), indices(2));
            tosearch = cell2mat(table2cell(inter));
            tosearch = prepare(app, tosearch);
            searcher = strcat("SELECT * FROM Sample WHERE Sample_ID = ", tosearch);
            searcher = select(app.conn, searcher);
            app.UITable2.Data = searcher;
            titlesview = searcher.Properties.VariableNames;
            app.UITable2.ColumnName = titlesview;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LIMSVersion10UIFigure = uifigure('Visible', 'off');
            app.LIMSVersion10UIFigure.Name = 'LIMS Version 1.0';
            app.LIMSVersion10UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseFcn, true);
            app.TabGroup = uitabgroup(app.LIMSVersion10UIFigure);
            app.AddsamplesTab = uitab(app.TabGroup);
            app.AddsamplesTab.Title = 'Add sample(s)';
            app.SampleCreationDateDatePicker_3Label = uilabel(app.AddsamplesTab);
            app.SampleCreationDateDatePicker_3Label.Text = 'Sample Creation Date';
            app.SampleCreationDateDatePicker_3 = uidatepicker(app.AddsamplesTab);
            app.SampleCreationDateDatePicker_3.DisplayFormat = 'uuuu-MM-dd';
            app.OptionalCollaboratorInformationPanel_2 = uipanel(app.AddsamplesTab);
            app.OptionalCollaboratorInformationPanel_2.Title = 'Optional Collaborator Information';
            app.ReceivedDateDatePicker_3Label = uilabel(app.OptionalCollaboratorInformationPanel_2);
            app.ReceivedDateDatePicker_3Label.Text = 'Received Date';
            app.ReceivedDateDatePicker_3 = uidatepicker(app.OptionalCollaboratorInformationPanel_2);
            app.ReceivedDateDatePicker_3.DisplayFormat = 'uuuu-MM-dd';
            app.ReceivingProtocolLinkEditField_3Label = uilabel(app.OptionalCollaboratorInformationPanel_2);
            app.ReceivingProtocolLinkEditField_3Label.Text = 'Receiving Protocol Link';
            app.ReceivingProtocolLinkEditField_3 = uieditfield(app.OptionalCollaboratorInformationPanel_2, 'text');
            app.ManufacturerInfoLinkEditField_3Label = uilabel(app.OptionalCollaboratorInformationPanel_2);
            app.ManufacturerInfoLinkEditField_3Label.Text = 'Manufacturer Info Link';
            app.ManufacturerInfoLinkEditField_3 = uieditfield(app.OptionalCollaboratorInformationPanel_2, 'text');
            app.WhoreceiveditDropDown_4Label = uilabel(app.OptionalCollaboratorInformationPanel_2);
            app.WhoreceiveditDropDown_4Label.Text = 'Who received it?';
            app.WhoreceiveditDropDown_4 = uidropdown(app.OptionalCollaboratorInformationPanel_2);
            app.WhoreceiveditDropDown_4.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.WhoreceiveditDropDown_4.Value = '';
            app.WherediditcomefromDropDown_2Label = uilabel(app.OptionalCollaboratorInformationPanel_2);
            app.WherediditcomefromDropDown_2Label.Text = 'Where did it come from?';
            app.WherediditcomefromDropDown_2 = uidropdown(app.OptionalCollaboratorInformationPanel_2);
            app.WherediditcomefromDropDown_2.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.WherediditcomefromDropDown_2.Editable = 'on';
            app.WherediditcomefromDropDown_2.Value = '';
            app.NumberofsamplestocreateEditFieldLabel = uilabel(app.AddsamplesTab);
            app.NumberofsamplestocreateEditFieldLabel.Text = 'Number of samples to create';
            app.NumberofsamplestocreateEditField = uieditfield(app.AddsamplesTab, 'numeric');
            app.NumberofsamplestocreateEditField.Value = 1;
            app.InitialNotesTextAreaLabel = uilabel(app.AddsamplesTab);
            app.InitialNotesTextAreaLabel.Text = 'Initial Notes ';
            app.InitialNotesTextArea = uitextarea(app.AddsamplesTab);
            app.AddSamplesButton = uibutton(app.AddsamplesTab, 'push');
            app.AddSamplesButton.ButtonPushedFcn = createCallbackFcn(app, @AddSamplesButtonPushed, true);
            app.AddSamplesButton.Text = 'Add Sample(s)';
            app.TypeLabel = uilabel(app.AddsamplesTab);
            app.TypeLabel.Text = '*Type';
            app.TypeDropDown_2 = uidropdown(app.AddsamplesTab);
            app.TypeDropDown_2.Items = {'', 'DNA', 'RNA', 'Protein'};
            app.TypeDropDown_2.Editable = 'on';
            app.TypeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @TypeDropDown_2ValueChanged, true);
            app.TypeDropDown_2.Value = '';
            app.DyeDropDown_2Label = uilabel(app.AddsamplesTab);
            app.DyeDropDown_2Label.Text = 'Dye';
            app.DyeDropDown_2 = uidropdown(app.AddsamplesTab);
            app.DyeDropDown_2.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.DyeDropDown_2.Editable = 'on';
            app.DyeDropDown_2.Value = '';
            app.MoleculeDropDown_2Label = uilabel(app.AddsamplesTab);
            app.MoleculeDropDown_2Label.Text = 'Molecule';
            app.MoleculeDropDown_2 = uidropdown(app.AddsamplesTab);
            app.MoleculeDropDown_2.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.MoleculeDropDown_2.Editable = 'on';
            app.MoleculeDropDown_2.Value = '';
            app.ParentIDEditField_3Label = uilabel(app.AddsamplesTab);
            app.ParentIDEditField_3Label.Text = 'Parent ID';
            app.ParentIDEditField_3 = uieditfield(app.AddsamplesTab, 'text');
            app.MolarMassEditFieldLabel = uilabel(app.AddsamplesTab);
            app.MolarMassEditFieldLabel.Text = 'Molar Mass';
            app.MolarMassEditField = uieditfield(app.AddsamplesTab, 'text');
            app.StorageBoxDropDown_2Label = uilabel(app.AddsamplesTab);
            app.StorageBoxDropDown_2Label.Text = 'Storage Box';
            app.StorageBoxDropDown_2 = uidropdown(app.AddsamplesTab);
            app.StorageBoxDropDown_2.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.StorageBoxDropDown_2.Editable = 'on';
            app.StorageBoxDropDown_2.Value = '';
            app.StorageBufferDropDown_2Label = uilabel(app.AddsamplesTab);
            app.StorageBufferDropDown_2Label.Text = 'Storage Buffer';
            app.StorageBufferDropDown_2 = uidropdown(app.AddsamplesTab);
            app.StorageBufferDropDown_2.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.StorageBufferDropDown_2.Editable = 'on';
            app.StorageBufferDropDown_2.Value = '';
            app.StorageLocationDropDown_2Label = uilabel(app.AddsamplesTab);
            app.StorageLocationDropDown_2Label.Text = 'Storage Location';
            app.StorageLocationDropDown_2 = uidropdown(app.AddsamplesTab);
            app.StorageLocationDropDown_2.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.StorageLocationDropDown_2.Editable = 'on';
            app.StorageLocationDropDown_2.Value = '';
            app.ProtocolLinksPanel = uipanel(app.AddsamplesTab);
            app.ProtocolLinksPanel.Title = 'Protocol Links';
            app.SurfaceTreatmentEditFieldLabel = uilabel(app.ProtocolLinksPanel);
            app.SurfaceTreatmentEditFieldLabel.Text = 'Surface Treatment ';
            app.SurfaceTreatmentEditField = uieditfield(app.ProtocolLinksPanel, 'text');
            app.HandlingProtocolsEditFieldLabel = uilabel(app.ProtocolLinksPanel);
            app.HandlingProtocolsEditFieldLabel.Text = 'Handling Protocols ';
            app.HandlingProtocolsEditField = uieditfield(app.ProtocolLinksPanel, 'text');
            app.ImagingBufferEditFieldLabel = uilabel(app.AddsamplesTab);
            app.ImagingBufferEditFieldLabel.Text = 'Imaging Buffer';
            app.ImagingBufferEditField = uieditfield(app.AddsamplesTab, 'text');
            app.ConcentrationAmountEditField_2Label = uilabel(app.AddsamplesTab);
            app.ConcentrationAmountEditField_2Label.Text = 'Concentration Amount';
            app.ConcentrationAmountEditField_2 = uieditfield(app.AddsamplesTab, 'numeric');
            app.ConcentrationAmountEditField_2.ValueChangedFcn = createCallbackFcn(app, @ConcentrationAmountEditField_2ValueChanged, true);
            app.QuantityEditField_2Label = uilabel(app.AddsamplesTab);
            app.QuantityEditField_2Label.Text = 'Quantity';
            app.QuantityEditField_2 = uieditfield(app.AddsamplesTab, 'numeric');
            app.QuantityEditField_2.ValueChangedFcn = createCallbackFcn(app, @QuantityEditField_2ValueChanged, true);
            app.WhosesampleisitDropDownLabel = uilabel(app.AddsamplesTab);
            app.WhosesampleisitDropDownLabel.Text = '*Whose sample is it?';
            app.WhosesampleisitDropDown = uidropdown(app.AddsamplesTab);
            app.WhosesampleisitDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDown_2ValueChanged, true);
            app.youmusthaveasampleownerandasampletypetoenabletheaddbuttonLabel = uilabel(app.AddsamplesTab);
            app.youmusthaveasampleownerandasampletypetoenabletheaddbuttonLabel.Text = {'*you must have a sample owner'; 'and a sample type to enable the add'; 'button*'};
            app.UnitsEditField_3Label = uilabel(app.AddsamplesTab);
            app.UnitsEditField_3Label.Text = 'Units';
            app.UnitsEditField_3 = uieditfield(app.AddsamplesTab, 'text');
            app.UnitsEditField_4Label = uilabel(app.AddsamplesTab);
            app.UnitsEditField_4Label.Text = 'Units';
            app.UnitsEditField_4 = uieditfield(app.AddsamplesTab, 'text');
            app.UpdatesampleTab = uitab(app.TabGroup);
            app.UpdatesampleTab.Title = 'Update sample';
            app.SampleIDEditFieldLabel = uilabel(app.UpdatesampleTab);
            app.SampleIDEditFieldLabel.Text = 'Sample ID';
            app.SampleIDEditField = uieditfield(app.UpdatesampleTab, 'text');
            app.DisplayInfoButton = uibutton(app.UpdatesampleTab, 'push');
            app.DisplayInfoButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayInfoButtonPushed, true);
            app.DisplayInfoButton.Text = 'Display Info';
            app.UpdateSampleButton = uibutton(app.UpdatesampleTab, 'push');
            app.UpdateSampleButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateSampleButtonPushed, true);
            app.UpdateSampleButton.Text = 'Update Sample';
            app.UITable = uitable(app.UpdatesampleTab);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.TypeDropDown_4Label = uilabel(app.UpdatesampleTab);
            app.TypeDropDown_4Label.Enable = 'off';
            app.TypeDropDown_4Label.Text = 'Type';
            app.TypeDropDown_4 = uidropdown(app.UpdatesampleTab);
            app.TypeDropDown_4.Items = {'', 'DNA', 'RNA', 'Protein'};
            app.TypeDropDown_4.Editable = 'on';
            app.TypeDropDown_4.ValueChangedFcn = createCallbackFcn(app, @Sample_Update_Appender, true);
            app.TypeDropDown_4.Enable = 'off';
            app.TypeDropDown_4.Value = '';
            app.DyeDropDown_3Label = uilabel(app.UpdatesampleTab);
            app.DyeDropDown_3Label.Text = 'Dye';
            app.DyeDropDown_3 = uidropdown(app.UpdatesampleTab);
            app.DyeDropDown_3.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.DyeDropDown_3.Editable = 'on';
            app.DyeDropDown_3.ValueChangedFcn = createCallbackFcn(app, @Sample_Update_Appender, true);
            app.DyeDropDown_3.Value = '';
            app.MoleculeDropDown_3Label = uilabel(app.UpdatesampleTab);
            app.MoleculeDropDown_3Label.Text = 'Molecule';
            app.MoleculeDropDown_3 = uidropdown(app.UpdatesampleTab);
            app.MoleculeDropDown_3.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.MoleculeDropDown_3.Editable = 'on';
            app.MoleculeDropDown_3.ValueChangedFcn = createCallbackFcn(app, @Sample_Update_Appender, true);
            app.MoleculeDropDown_3.Value = '';
            app.ParentIDEditField_5Label = uilabel(app.UpdatesampleTab);
            app.ParentIDEditField_5Label.Text = 'Parent ID';
            app.ParentIDEditField_5 = uieditfield(app.UpdatesampleTab, 'text');
            app.ParentIDEditField_5.ValueChangedFcn = createCallbackFcn(app, @Sample_Update_Appender, true);
            app.MolarMassEditField_2Label = uilabel(app.UpdatesampleTab);
            app.MolarMassEditField_2Label.Text = 'Molar Mass';
            app.MolarMassEditField_2 = uieditfield(app.UpdatesampleTab, 'text');
            app.MolarMassEditField_2.ValueChangedFcn = createCallbackFcn(app, @Sample_Update_Appender, true);
            app.StorageBoxDropDown_3Label = uilabel(app.UpdatesampleTab);
            app.StorageBoxDropDown_3Label.Text = 'Storage Box';
            app.StorageBoxDropDown_3 = uidropdown(app.UpdatesampleTab);
            app.StorageBoxDropDown_3.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.StorageBoxDropDown_3.Editable = 'on';
            app.StorageBoxDropDown_3.ValueChangedFcn = createCallbackFcn(app, @Sample_Update_Appender, true);
            app.StorageBoxDropDown_3.Value = '';
            app.StorageBufferDropDown_3Label = uilabel(app.UpdatesampleTab);
            app.StorageBufferDropDown_3Label.Text = 'Storage Buffer';
            app.StorageBufferDropDown_3 = uidropdown(app.UpdatesampleTab);
            app.StorageBufferDropDown_3.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.StorageBufferDropDown_3.Editable = 'on';
            app.StorageBufferDropDown_3.ValueChangedFcn = createCallbackFcn(app, @Sample_Update_Appender, true);
            app.StorageBufferDropDown_3.Value = '';
            app.StorageLocationDropDown_4Label = uilabel(app.UpdatesampleTab);
            app.StorageLocationDropDown_4Label.Text = 'Storage Location';
            app.StorageLocationDropDown_4 = uidropdown(app.UpdatesampleTab);
            app.StorageLocationDropDown_4.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.StorageLocationDropDown_4.Editable = 'on';
            app.StorageLocationDropDown_4.ValueChangedFcn = createCallbackFcn(app, @Sample_Update_Appender, true);
            app.StorageLocationDropDown_4.Value = '';
            app.QuantityEditField_3Label = uilabel(app.UpdatesampleTab);
            app.QuantityEditField_3Label.Text = 'Quantity';
            app.QuantityEditField_3 = uieditfield(app.UpdatesampleTab, 'numeric');
            app.QuantityEditField_3.ValueChangedFcn = createCallbackFcn(app, @Sample_Update_Appender, true);
            app.WhosesampleisitDropDown_2Label = uilabel(app.UpdatesampleTab);
            app.WhosesampleisitDropDown_2Label.Text = 'Whose sample is it?';
            app.WhosesampleisitDropDown_2 = uidropdown(app.UpdatesampleTab);
            app.WhosesampleisitDropDown_2.ValueChangedFcn = createCallbackFcn(app, @Sample_Update_Appender, true);
            app.OptionalCollaboratorInformationPanel_3 = uipanel(app.UpdatesampleTab);
            app.OptionalCollaboratorInformationPanel_3.Title = 'Optional Collaborator Information';
            app.ReceivedDateDatePicker_4Label = uilabel(app.OptionalCollaboratorInformationPanel_3);
            app.ReceivedDateDatePicker_4Label.Text = 'Received Date';
            app.ReceivedDateDatePicker_4 = uidatepicker(app.OptionalCollaboratorInformationPanel_3);
            app.ReceivedDateDatePicker_4.DisplayFormat = 'uuuu-MM-dd';
            app.ReceivedDateDatePicker_4.ValueChangedFcn = createCallbackFcn(app, @RID_Update_Appender, true);
            app.ReceivingProtocolLinkEditField_4Label = uilabel(app.OptionalCollaboratorInformationPanel_3);
            app.ReceivingProtocolLinkEditField_4Label.Text = 'Receiving Protocol Link';
            app.ReceivingProtocolLinkEditField_4 = uieditfield(app.OptionalCollaboratorInformationPanel_3, 'text');
            app.ReceivingProtocolLinkEditField_4.ValueChangedFcn = createCallbackFcn(app, @RID_Update_Appender, true);
            app.ManufacturerInfoLinkEditField_4Label = uilabel(app.OptionalCollaboratorInformationPanel_3);
            app.ManufacturerInfoLinkEditField_4Label.Text = 'Manufacturer Info Link';
            app.ManufacturerInfoLinkEditField_4 = uieditfield(app.OptionalCollaboratorInformationPanel_3, 'text');
            app.ManufacturerInfoLinkEditField_4.ValueChangedFcn = createCallbackFcn(app, @RID_Update_Appender, true);
            app.WhoreceiveditDropDown_5Label = uilabel(app.OptionalCollaboratorInformationPanel_3);
            app.WhoreceiveditDropDown_5Label.Text = 'Who received it?';
            app.WhoreceiveditDropDown_5 = uidropdown(app.OptionalCollaboratorInformationPanel_3);
            app.WhoreceiveditDropDown_5.ValueChangedFcn = createCallbackFcn(app, @RID_Update_Appender, true);
            app.WherediditcomefromDropDown_3Label = uilabel(app.OptionalCollaboratorInformationPanel_3);
            app.WherediditcomefromDropDown_3Label.Text = 'Where did it come from?';
            app.WherediditcomefromDropDown_3 = uidropdown(app.OptionalCollaboratorInformationPanel_3);
            app.WherediditcomefromDropDown_3.Editable = 'on';
            app.WherediditcomefromDropDown_3.ValueChangedFcn = createCallbackFcn(app, @RID_Update_Appender, true);
            app.ProtocolLinksPanel_2 = uipanel(app.UpdatesampleTab);
            app.ProtocolLinksPanel_2.Title = 'Protocol Links';
            app.SurfaceTreatmentEditField_2Label = uilabel(app.ProtocolLinksPanel_2);
            app.SurfaceTreatmentEditField_2Label.Text = 'Surface Treatment ';
            app.SurfaceTreatmentEditField_2 = uieditfield(app.ProtocolLinksPanel_2, 'text');
            app.SurfaceTreatmentEditField_2.ValueChangedFcn = createCallbackFcn(app, @PID_Update_Appender, true);
            app.HandlingProtocolsEditField_2Label = uilabel(app.ProtocolLinksPanel_2);
            app.HandlingProtocolsEditField_2Label.Text = 'Handling Protocols ';
            app.HandlingProtocolsEditField_2 = uieditfield(app.ProtocolLinksPanel_2, 'text');
            app.HandlingProtocolsEditField_2.ValueChangedFcn = createCallbackFcn(app, @PID_Update_Appender, true);
            app.ConcentrationAmountEditField_3Label = uilabel(app.UpdatesampleTab);
            app.ConcentrationAmountEditField_3Label.Text = 'Concentration Amount';
            app.ConcentrationAmountEditField_3 = uieditfield(app.UpdatesampleTab, 'numeric');
            app.ConcentrationAmountEditField_3.ValueChangedFcn = createCallbackFcn(app, @Sample_Update_Appender, true);
            app.SampleCreationDateDatePicker_5Label = uilabel(app.UpdatesampleTab);
            app.SampleCreationDateDatePicker_5Label.Text = 'Sample Creation Date';
            app.SampleCreationDateDatePicker_5 = uidatepicker(app.UpdatesampleTab);
            app.SampleCreationDateDatePicker_5.DisplayFormat = 'uuuu-MM-dd';
            app.SampleCreationDateDatePicker_5.ValueChangedFcn = createCallbackFcn(app, @Sample_Update_Appender, true);
            app.StatusDropDownLabel = uilabel(app.UpdatesampleTab);
            app.StatusDropDownLabel.Text = 'Status';
            app.StatusDropDown = uidropdown(app.UpdatesampleTab);
            app.StatusDropDown.ValueChangedFcn = createCallbackFcn(app, @Sample_Update_Appender, true);
            app.FridgeRetrievalNumberEditFieldLabel = uilabel(app.UpdatesampleTab);
            app.FridgeRetrievalNumberEditFieldLabel.Text = 'Fridge Retrieval Number';
            app.FridgeRetrievalNumberEditField = uieditfield(app.UpdatesampleTab, 'numeric');
            app.FridgeRetrievalNumberEditField.ValueChangedFcn = createCallbackFcn(app, @Sample_Update_Appender, true);
            app.UnitsEditFieldLabel = uilabel(app.UpdatesampleTab);
            app.UnitsEditFieldLabel.Text = 'Units';
            app.UnitsEditField = uieditfield(app.UpdatesampleTab, 'text');
            app.UnitsEditField_2Label = uilabel(app.UpdatesampleTab);
            app.UnitsEditField_2Label.Text = 'Units';
            app.UnitsEditField_2 = uieditfield(app.UpdatesampleTab, 'text');
            app.AddNotesTab = uitab(app.TabGroup);
            app.AddNotesTab.Title = 'Add Notes';
            app.NotesTextArea_2Label = uilabel(app.AddNotesTab);
            app.NotesTextArea_2Label.Text = 'Notes';
            app.NotesTextArea_2 = uitextarea(app.AddNotesTab);
            app.NotesTextArea_2.Enable = 'off';
            app.ImagingBufferEditField_3Label = uilabel(app.AddNotesTab);
            app.ImagingBufferEditField_3Label.Text = 'Imaging Buffer';
            app.ImagingBufferEditField_3 = uieditfield(app.AddNotesTab, 'text');
            app.ImagingBufferEditField_3.Enable = 'off';
            app.WhatsamplewouldyouliketoaddnotesforEditFieldLabel = uilabel(app.AddNotesTab);
            app.WhatsamplewouldyouliketoaddnotesforEditFieldLabel.Text = 'What sample would you like to add notes for?';
            app.WhatsamplewouldyouliketoaddnotesforEditField = uieditfield(app.AddNotesTab, 'text');
            app.WhatsamplewouldyouliketoaddnotesforEditField.ValueChangingFcn = createCallbackFcn(app, @WhatsamplewouldyouliketoaddnotesforEditFieldValueChanging, true);
            app.CheckIDButton = uibutton(app.AddNotesTab, 'push');
            app.CheckIDButton.ButtonPushedFcn = createCallbackFcn(app, @CheckIDButtonPushed, true);
            app.CheckIDButton.Text = 'Check ID';
            app.AddnewnotesButton_2 = uibutton(app.AddNotesTab, 'push');
            app.AddnewnotesButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddnewnotesButtonPushed, true);
            app.AddnewnotesButton_2.Text = 'Add new notes';
            app.UITable3 = uitable(app.AddNotesTab);
            app.UITable3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable3.RowName = {};
            app.ViewsamplesTab = uitab(app.TabGroup);
            app.ViewsamplesTab.Title = 'View sample(s)';
            app.UITable2 = uitable(app.ViewsamplesTab);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.UITable2.CellSelectionCallback = createCallbackFcn(app, @UITable2CellSelection, true);
            app.SearchButton = uibutton(app.ViewsamplesTab, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.UITable2_2 = uitable(app.ViewsamplesTab);
            app.UITable2_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2_2.RowName = {};
            app.UITable2_2.CellSelectionCallback = createCallbackFcn(app, @UITable2_2CellSelection, true);
            app.ChooseinformationtodisplayPanel = uipanel(app.ViewsamplesTab);
            app.ChooseinformationtodisplayPanel.Title = 'Choose information to display ';
            app.QuantityInformationVolumeConcetcCheckBox = uicheckbox(app.ChooseinformationtodisplayPanel);
            app.QuantityInformationVolumeConcetcCheckBox.Text = 'Quantity Information (Volume, Conc etc)';
            app.StorageInformationBufferBoxLocationCheckBox = uicheckbox(app.ChooseinformationtodisplayPanel);
            app.StorageInformationBufferBoxLocationCheckBox.Text = 'Storage Information (Buffer, Box, Location)';
            app.ReceivingInformationOriginDatereceivedetcCheckBox = uicheckbox(app.ChooseinformationtodisplayPanel);
            app.ReceivingInformationOriginDatereceivedetcCheckBox.Text = 'Receiving Information (Origin, Date received etc)';
            app.TreatmentandHandlingProtocolLinksCheckBox = uicheckbox(app.ChooseinformationtodisplayPanel);
            app.TreatmentandHandlingProtocolLinksCheckBox.Text = 'Treatment and Handling Protocol Links';
            app.SampleOwnerDropDownLabel = uilabel(app.ViewsamplesTab);
            app.SampleOwnerDropDownLabel.Enable = 'off';
            app.SampleOwnerDropDownLabel.Text = 'Sample Owner';
            app.SampleOwnerDropDown = uidropdown(app.ViewsamplesTab);
            app.SampleOwnerDropDown.Enable = 'off';
            app.CheckBox = uicheckbox(app.ViewsamplesTab);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.ParentIDDropDownLabel = uilabel(app.ViewsamplesTab);
            app.ParentIDDropDownLabel.Enable = 'off';
            app.ParentIDDropDownLabel.Text = 'Parent ID';
            app.ParentIDDropDown = uidropdown(app.ViewsamplesTab);
            app.ParentIDDropDown.Enable = 'off';
            app.SearchKeywordEditFieldLabel = uilabel(app.ViewsamplesTab);
            app.SearchKeywordEditFieldLabel.Text = 'Search Keyword';
            app.SearchKeywordEditField = uieditfield(app.ViewsamplesTab, 'text');
            app.SearchNotesButton = uibutton(app.ViewsamplesTab, 'push');
            app.SearchNotesButton.ButtonPushedFcn = createCallbackFcn(app, @SearchNotesButtonPushed, true);
            app.SearchNotesButton.Text = 'Search Notes';
            app.DisplayallnotesButton = uibutton(app.ViewsamplesTab, 'push');
            app.DisplayallnotesButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayallnotesButtonPushed, true);
            app.DisplayallnotesButton.Text = 'Display all notes ';
            app.StatusDropDown_2Label = uilabel(app.ViewsamplesTab);
            app.StatusDropDown_2Label.Enable = 'off';
            app.StatusDropDown_2Label.Text = 'Status ';
            app.StatusDropDown_2 = uidropdown(app.ViewsamplesTab);
            app.StatusDropDown_2.Enable = 'off';
            app.CheckBox_2 = uicheckbox(app.ViewsamplesTab);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_2.Text = '';
            app.CheckBox_3 = uicheckbox(app.ViewsamplesTab);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_3.Text = '';
            % Show the figure after all components are created
            app.LIMSVersion10UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = draftinterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LIMSVersion10UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LIMSVersion10UIFigure)
        end
    end
end